classdef game1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InstructionsPanel       matlab.ui.container.Panel
        HowtoPlayLabel          matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        BacktoHomeButton        matlab.ui.control.Button
        PlayPanel               matlab.ui.container.Panel
        WordCountdownLabel      matlab.ui.control.Label
        SubmitButton            matlab.ui.control.Button
        EditField5              matlab.ui.control.EditField
        EditField4              matlab.ui.control.EditField
        EditField3              matlab.ui.control.EditField
        EditField2              matlab.ui.control.EditField
        EditField               matlab.ui.control.EditField
        Button5                 matlab.ui.control.Button
        Button4                 matlab.ui.control.Button
        Button3                 matlab.ui.control.Button
        Button2                 matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        GameModePanel           matlab.ui.container.Panel
        BeginButton             matlab.ui.control.Button
        HowtoPlayButton         matlab.ui.control.Button
        HomeLabel               matlab.ui.control.Label
        WelcomePanel            matlab.ui.container.Panel
        CLPS0950March2025Label  matlab.ui.control.Label
        ByKiaraIbanezDiazandOliviaWangLabel  matlab.ui.control.Label
        Button_2                matlab.ui.control.Button
        WELCOMETOLabel          matlab.ui.control.Label
    end

   %edited by olivia March 20, 2025 6:56pm-
    %objectives:
        %debugging game
      

    %kiara
    properties (Access = private)
        DraggingTile  matlab.ui.control.Button
        WordList string
        Dictionary;
        CurrentLetters
        PossibleWords
        FoundWords
        RemainingWordsCount
        wordSlots
    end
    
    %kiara
    properties (Access = public)
        FoundWordsList          matlab.ui.control.ListBox 
        EditFieldContainer      matlab.ui.container.Panel
    end
    

    %kiara
    methods (Access = private)

        
        function startupFcn(app)
            app.BacktoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoHomeButtonPushed, true);
            showPanel(app, app.WelcomePanel);
        end


        %kiara
       function showPanel(app, panel)
            % Hide all panels
            app.WelcomePanel.Visible = 'off';
            app.GameModePanel.Visible = 'off';
            app.PlayPanel.Visible = 'off';
            app.InstructionsPanel.Visible = 'off';

            % Show the selected panel
            panel.Visible = 'on';
        end


        %Dictionary and Word Validation Code%
        %kiara
        function loadDictionary(app)
            persistent dictionary;
            if isempty(dictionary)
                fid = fopen('words.txt');
                dictionary = textscan (fid, '%s');
                dictionary = dictionary {1};
                fclose(fid);
            end
            app.Dictionary = dictionary;
        end


        %kiara
         function checkWord(app)
            % Collect the text from each word cell from dictionary
            formedWord = '';
            

            % Loop through each letter of the word and concatenate the text
            for i = 1:length(app.wordSlots)
                formedWord = [formedWord, app.wordSlots(i).Value];  % Concatenate the letter in the slot
            end

            % Check if the formed word is valid (you could add your own dictionary here)

            % Display a message based on whether the word is valid or not
           if length(formedWord) ~= length(app.wordSlots)
            uialert(app.UIFigure, 'Please use all available slots.', 'Incomplete Word');
            return;
           end
    
            % Convert to uppercase for consistent comparison
            formedWord = upper(formedWord);

            disp(['Formed word', formedWord]);
    
            % Load the dictionary if not already loaded
            if isempty(app.Dictionary)
                app.loadDictionary();
            end

            isValid = ismember(formedWord, app.PossibleWords);
            alreadyFound = ismember(formedWord, app.FoundWords);

            if isValid && ~alreadyFound
                app.FoundWords{end+1} = formedWord;
                app.RemainingWordsCount = app.RemainingWordsCount -1;

                app.FoundWordsList.Items = app.FoundWords;

                app.WordCountdownLabel.Text = ['Words left: ', num2str(app.RemainingWordsCount)];

                if app.RemainingWordsCount > 0
                    uialert(app.UIFigure, ['Correct word!', num2str(app.RemainingWordsCount), 'left to go'], 'Success');
                else
                    uialert(app.UIFigure, 'Congratulations! You found all the words! You Win!');

                    pause(2);
                    app.showPanel(app.GameModePanel);
                    return;
                end

                app.resetTiles();
            elseif alreadyFound
                uialert(app.UIFigure, 'You already found this word!', 'Duplicate Word');
                app.resetTiles();
            end
         end


         function PossibleWords = findAllPossibleWords(app, letters)
             PossibleWords = {};

             %changed/added
             if isempty(app.Dictionary)
                 app.loadDictionary();
             end
                     
             if iscell(letters)
                 lettersStr = letters{1};
             else
                 lettersStr = letters;
             end 


             for i = 1:length(app.Dictionary)
                 word = upper(app.Dictionary{i});

                 if length(word) > length(letters)
                     continue;
                 end

                 if app.canFormWord(word, lettersStr)
                     PossibleWords{end+1} = word;
                 end
             end
         end

         function result = canFormWord(app, word, letters)
             
            
             word = upper(word);
             letters = upper(letters); 
             remainingLetters = letters;

             for i=1:length(word)
                 letter = word(i);
                 found = false;


% added     
            if iscell(remainingLetters)
                remainingLettersStr= remainingLetters{1};

            else
                remainingLettersStr = remainingLetters;
            end 


                 for j = 1:length(remainingLettersStr)
                     if letter == remainingLettersStr(j)
                         if iscell(remainingLetters)
                             remainingLettersStr(j) = [];
                             remainingLetters{1} = remainingLettersStr;
                         else
                             remainingLetters(j) = [];
                         end 
                         
                         found = true;
                         break;
                     end
                 end

                 if ~found
                     result = false;
                     return;
                 end
             end

             result = true;
         end
    

         function generateGame(app)
             disp('Generating game with random letters')

             if isempty(app.Dictionary)
                 app.loadDictionary();
             end

             if isempty(app.Dictionary)
                 uialert(app.UIFigure, 'Failed to load dictionary. Please check if dictionary7.txt exists.', 'Error');
                 return;
             end

             potentialWords = app.Dictionary(cellfun(@length, app.Dictionary)>=3 & cellfun(@length, app.Dictionary)<= 7);

             if isempty(potentialWords)
                 error('No suitable words found in the dictionary');
             end

             bestLetters = '';
             bestWordCount = 0;
             maxAttempts = 50;

             for attempt = 1:maxAttempts
                 randomIndex = randi(length(potentialWords));
                 selectedWord = upper(potentialWords(randomIndex));

                 %changed/added app.possiblewords
                 app.PossibleWords = app.findAllPossibleWords(selectedWord);
             
                 if length(app.PossibleWords) > bestWordCount && length(app.PossibleWords) <= 10
                     bestLetters = selectedWord;
                     bestWordCount = length(app.PossibleWords);

                     if bestWordCount >=3
                         break;
                     end
                 end
             end

             if bestWordCount < 2
                 bestLetters = selectedWord;
             end

             app.CurrentLetters = bestLetters;

             app.PossibleWords = app.findAllPossibleWords(bestLetters);
             app.FoundWords = {};
             app.RemainingWordsCount = length(app.PossibleWords);

             app.WordCountdownLabel.Text = ['Words left:', num2str(app.RemainingWordsCount)];

             app.FoundWordsList.Items = {};

                %debugging - olivia
             scrambledIndices = randperm(length(bestLetters));
             scrambledLetters = bestLetters(scrambledIndices);

             letterButtons = [app.Button, app.Button2, app.Button3, app.Button4, app.Button5];

             for i= 1:min(length(scrambledLetters), length(letterButtons))
                     letterButtons(i).Visible = 'on';
                     letterButtons(i).Text = scrambledLetters(i);
                     letterButtons(i).UserData.originalPosition = letterButtons(i).Position;
                     letterButtons(i).BackgroundColor = [0.8 0.8 1];
                     letterButtons(i).FontSize = 16;
                     letterButtons(i).FontWeight = 'bold';
             end 
                     
            
                     disp(['button' ,num2str(i), ' set to display letter: ', scrambledLetters(i)])
               for j = length(scrambledLetters) + 1:length(letterButtons)
                   letterButtons(i).Visible = 'off';
               end


             disp(['Letters:', bestLetters]);
             disp(['Possiblewords:', num2str(length(app.PossibleWords))]);
             for i=1:length(app.PossibleWords)
                 disp([' ', app.PossibleWords{i}]);
             end

             if ~isempty(app.PossibleWords)
                 firstWordLength = length(app.PossibleWords{1});
                 disp(['Creating ', num2str(firstWordLength), ' word slots for words like: ', app.PossibleWords{1}]);
                app.createWordSlots(firstWordLength);
             else 
                 disp('warning no possible words found!');
             end

%disp('letter assignments:')
%for i = 1:length(letterButtons)
    %if  strcmp(letterButtons(i).Visible, 'on')
     %   disp(['Button ' num2str(i) ' text: ' letterButtons(i).Text])
    %end
   
%end 


             drawnow;
         end

% debugging - olivia
         function createWordSlots(app, wordLength)
            delete(findall(app.EditFieldContainer, 'Type', 'uieditfield'));
            app.wordSlots = [];
            
            slotWidth = 34;
            slotHeight = 32;
            containerWidth = app.EditFieldContainer.Position(3);
            totalWidth = slotWidth * wordLength;
            startX = (containerWidth - totalWidth) / 2;

            disp(['Creating ', num2str(wordLength), ' word slots']);
            disp(['Container width: ', num2str(containerWidth)]);
            disp(['Starting X position: ', num2str(startX)]);
            
            for i = 1:wordLength
                slot = uieditfield(app.EditFieldContainer, 'text');
                slot.Position = [startX + (i-1)*slotWidth, 0, slotWidth, slotHeight];
                slot.Tag = 'WordSlot';
                slot.BackgroundColor = [0.9 0.9 0.9];
                slot.HorizontalAlignment = 'center';
                slot.FontSize = 14;
                slot.Visible = 'on';
                slot.Value = ''; %added - olivia
                slot.Editable = 'off'; %added - olivia 
                app.wordSlots(i) = slot;


                disp(['Created slot', num2str(i), 'at position', num2str(slot.Position)]);
            end

            app.EditFieldContainer.Visible = 'on'; %added
          end


        %kiara
        

        %% Drag and Drop Code

         %kiara
        function startTileDrag(app, src, ~)
             if isa(src, 'matlab.ui.control.Button')
                app.DraggingTile = src;

                if isfield(app.DraggingTile.UserData, 'slotIndex') && ~isempty(app.DraggingTile.UserData.slotIndex)
                    slotIndex = app.DraggingTile.UserData.slotIndex;
                    app.wordSlots(slotIndex).Value = '';
                end
        
                % Store original position for return if needed
                %kiara
                if isempty(app.DraggingTile.UserData)
                    app.DraggingTile.UserData = struct();
                end
        
                % Store original position for return if needed
                %kiara
                app.DraggingTile.UserData.originalPosition = app.DraggingTile.Position;

        
                uistack(app.DraggingTile, 'top');  % Alternative to direct ZOrder access
                app.DraggingTile.FontWeight = 'bold';
                app.DraggingTile.BackgroundColor = [0.9 0.7 0.7];
                % Set up event handlers
                %kiara
                set(app.UIFigure, 'WindowButtonMotionFcn', @(src,event)app.dragTile(event));
                set(app.UIFigure, 'WindowButtonUpFcn', @(src,event)app.dropTile(event));
        
                disp(['Dragging ', app.DraggingTile.Text]);
            else
                disp('Invalid drag source');
            end
        end
        
        %kiara
        function dragTile(app, ~)
           if ~isempty(app.DraggingTile)
                % Get current mouse position relative to the figure
                currentPoint = app.UIFigure.CurrentPoint;
        
                % Keep the tile size the same
                tileWidth = app.DraggingTile.Position(3);
                tileHeight = app.DraggingTile.Position(4);
        
                % Center the tile on the mouse cursor
                newX = currentPoint(1) - (tileWidth / 2);
                newY = currentPoint(2) - (tileHeight / 2);
        
                % Make sure the tile stays within the figure bounds
                figWidth = app.UIFigure.Position(3);
                figHeight = app.UIFigure.Position(4);
        
                newX = max(0, min(newX, figWidth - tileWidth));
                newY = max(0, min(newY, figHeight - tileHeight));
        
                % Update the tile position
                %kiara
                app.DraggingTile.Position = [newX, newY, tileWidth, tileHeight];

                drawnow update;
            end
        end

        function dropTile(app, ~)
            if isempty(app.DraggingTile)
                return;
            end

            app.DraggingTile.FontWeight = 'normal';
            app.DraggingTile.BackgroundColor = [0.8 0.8 1];
    
            dropPos = app.UIFigure.CurrentPoint;
    
            if any(isnan(dropPos)) || any(isinf(dropPos))
                disp('Invalid drop position');
                set(app.UIFigure, 'WindowButtonMotionFcn', '');
                set(app.UIFigure, 'WindowButtonUpFcn', '');
                return;
            end
    
            placed = false;

            for i = 1:length(app.wordSlots)
                if app.isOverlapping(app.DraggingTile, app.wordSlots(i))
                    % Check if the slot already has a value
                    if ~isempty(app.wordSlots(i).Value)
                        disp(['Slot', num2str(i), 'is already occupied']);

                        continue;
                    end
            

                    slotPos = app.wordSlots(i).Position;
                    containerPos = app.EditFieldContainer.Position;

                    newX = containerPos(1) + slotPos(1) -1;
               
                    newY = containerPos(2) + slotPos(2) -1;
            
                    app.DraggingTile.Position(1) = newX;
                    app.DraggingTile.Position(2) = newY;
            
                    uistack(app.DraggingTile, 'top');

                    % Return to original position
                    if isfield(app.DraggingTile.UserData, 'slotIndex') && ~isempty(app.DraggingTile.UserData.slotIndex)
                        prevSlotIndex = app.DraggingTile.UserData.slotIndex;
                        app.wordSlots(prevSlotIndex).Value = '';
                    end

                    app.DraggingTile.UserData.slotIndex = i; 
        
        
                        % Assign the value to the slot
                    app.wordSlots(i).Value = app.DraggingTile.Text;
                    app.wordSlots(i).FontColor = [0 0 0];
                    placed = true;
                    disp(['Placed in slot ', num2str(i)]);
                    break;
                end
            end

            if ~placed
           
                if isfield(app.DraggingTile.UserData, 'originalPosition')
                    app.DraggingTile.Position = app.DraggingTile.UserData.originalPosition;
                end
                disp('Tile not placed in any valid slot');
            end
                
            set(app.UIFigure, 'WindowButtonMotionFcn', '');
            set(app.UIFigure, 'WindowButtonUpFcn', '');
    
           
            drawnow;
        end


        % Kiara
        function letterButtonClicked(app, event)

            clickedButton = event.Source;
    
                % Check if this button is in a slot
                if isfield(clickedButton.UserData, 'slotIndex') && ~isempty(clickedButton.UserData.slotIndex)
                    slotIndex = clickedButton.UserData.slotIndex;
                    % Clear the slot
                    app.wordSlots(slotIndex).Value = '';
        
                    % Clear the slot association
                    clickedButton.UserData.slotIndex = [];
                end
    
                % Start dragging the button
                app.startTileDrag(clickedButton);
        end


        %kiara
        function overlap = isOverlapping(app, tile, slot)
             containerPos = app.EditFieldContainer.Position;
                tilePos = tile.Position;
                slotPos = slot.Position;

                absoluteSlotPos = [containerPos(1) +slotPos(1), containerPos(2) + slotPos(2), slotPos(3), slotPos(4)];

             tileCenter = [tilePos(1) + tilePos(3)/2, tilePos(2) + tilePos(4)/2];
             slotCenter = [absoluteSlotPos(1) + absoluteSlotPos(3)/2, absoluteSlotPos(2) + absoluteSlotPos(4)/2];

             distance = sqrt((tileCenter(1) - slotCenter(1))^2 + (tileCenter(2) - slotCenter(2))^2);

             overlap = (distance<40); %changed

             if overlap
                 disp(['Tile center: (', num2str(tileCenter(1)), ',', num2str(tileCenter(2)), ')']);
                 disp(['Slot center: (', num2str(slotCenter(1)), ',', num2str(slotCenter(2)), ')']);
                 disp(['Distance: ', num2str(distance)]);
             end

        end


        function resetTiles(app)
            letterButtons = [app.Button, app.Button2, app.Button3, app.Button4, app.Button5];
    
            % Clear all slots
            for i = 1:length(app.wordSlots)
                app.wordSlots(i).Value = '';
            end
    
            % Reset buttons to their original positions
            for i = 1:length(letterButtons)
                button = letterButtons(i);

                if strcmp(button.Visible, 'off')
                    continue;
                end
        
                % If the button was in a slot, clear that association
                if isfield(button.UserData, 'slotIndex') && ~isempty(button.UserData.slotIndex)
                    button.UserData.slotIndex = [];
                end
        
                % Return to original position if stored
                if isfield(button.UserData, 'originalPosition')
                    button.Position = button.UserData.originalPosition;
                else
                    % Fallback positions if original positions weren't stored
                    % These can be adjusted based on your layout
                    baseX = 50 + (i-1)*55;
                    button.Position(1) = baseX;
                    button.Position(2) = 196;
                end

                button.FontWeight = 'normal';
                button.BackgroundColor = [0.8 0.8 1];
        
                % Make sure all buttons are visible
                uistack(button, 'top');
            end
    
            drawnow;
        end

    function showGameModeScreen(app)
        app.WelcomePanel.Visible = 'off';  % Hide WelcomePanel
        app.GameModePanel.Visible = 'on';  % Show GameModePanel
    end

    function showPlayScreen(app)
        % Hide the GameModePanel and show the PlayPanel
        app.GameModePanel.Visible = 'off';  % Hide GameModePanel
        app.PlayPanel.Visible = 'on';

        if isempty(app.EditFieldContainer) || ~isvalid(app.EditFieldContainer)
        app.EditFieldContainer = uipanel(app.PlayPanel);
        app.EditFieldContainer.BorderType = 'none';
        app.EditFieldContainer.BackgroundColor = app.PlayPanel.BackgroundColor; % Match parent color
        app.EditFieldContainer.Position = [70 80 250 40];    
        end
    
    % Create FoundWordsList if it doesn't exist
    if isempty(app.FoundWordsList) || ~isvalid(app.FoundWordsList)
        app.FoundWordsList = uilistbox(app.PlayPanel);
        app.FoundWordsList.Position = [300 80 55 130]; % Adjust position as needed
        app.FoundWordsList.Items = {};
        
        % Optionally create a label for the list
        foundWordsLabel = uilabel(app.PlayPanel);
        foundWordsLabel.Position = [300 210 80 22];
        foundWordsLabel.Text = 'Found Words:';
    end
        
        %debugging - olivia
        app.EditField.Visible = 'off';
        app.EditField2.Visible = 'off';
        app.EditField3.Visible = 'off';
        app.EditField4.Visible = 'off';
        app.EditField5.Visible = 'off';
    
        letterButtons = [app.Button, app.Button2, app.Button3, app.Button4, app.Button5];
        for i=1:length(letterButtons)
            letterButtons(i).Visible = 'on';
            letterButtons(i).ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            letterButtons(i).BackgroundColor = [0.8 0.8 1];
            letterButtons(i).FontSize = 16;
            letterButtons(i).FontWeight = 'bold';
            letterButtons(i).Tooltip = 'Drag me to a slot';
        end
            app.generateGame();

    end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.showPanel(app.GameModePanel);
        end

        % Button pushed function: HowtoPlayButton
        function HowtoPlayButtonPushed(app, event)
            app.showPanel(app.InstructionsPanel);

        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            app.showPanel(app.PlayPanel);
            app.showPlayScreen();

        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.checkWord();
        end

        % Button pushed function: Button, Button2, Button3, Button4, 
        % ...and 1 other component
        function LetterTilesClicked(app, event)
        clickedButton = event.Source;
    
            if isfield(clickedButton.UserData, 'slotIndex') && ~isempty(clickedButton.UserData.slotIndex)
                slotIndex = clickedButton.UserData.slotIndex;
        
                % Clear the slot
                app.wordSlots(slotIndex).Value = '';

                clickedButton.UserData.slotIndex = [];

        
                % Return the button to its original position
                if isfield(clickedButton.UserData, 'originalPosition')
                    clickedButton.Position = clickedButton.UserData.originalPosition;
                    drawnow;
                    return;
                end
            end
   
    app.startTileDrag(clickedButton);
        end

        % Button pushed function: BacktoHomeButton
        function BacktoHomeButtonPushed(app, event)
            app.showPanel(app.GameModePanel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomePanel = uipanel(app.UIFigure);
            app.WelcomePanel.Title = 'Welcome Panel';
            app.WELCOMETOLabel = uilabel(app.WelcomePanel);
            app.WELCOMETOLabel.Text = 'WELCOME TO';
            app.Button_2 = uibutton(app.WelcomePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'anagram.gif');
            app.Button_2.Text = '';
            app.ByKiaraIbanezDiazandOliviaWangLabel = uilabel(app.WelcomePanel);
            app.ByKiaraIbanezDiazandOliviaWangLabel.Text = 'By Kiara Ibanez-Diaz and Olivia Wang';
            app.CLPS0950March2025Label = uilabel(app.WelcomePanel);
            app.CLPS0950March2025Label.Text = 'CLPS 0950 March 2025';
            app.GameModePanel = uipanel(app.UIFigure);
            app.GameModePanel.Title = 'GameModePanel';
            app.GameModePanel.Visible = 'off';
            app.HomeLabel = uilabel(app.GameModePanel);
            app.HomeLabel.Text = 'Home';
            app.HowtoPlayButton = uibutton(app.GameModePanel, 'push');
            app.HowtoPlayButton.ButtonPushedFcn = createCallbackFcn(app, @HowtoPlayButtonPushed, true);
            app.HowtoPlayButton.Text = 'How to Play';
            app.BeginButton = uibutton(app.GameModePanel, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.PlayPanel = uipanel(app.UIFigure);
            app.PlayPanel.Title = 'PlayPanel';
            app.PlayPanel.Visible = 'off';
            app.Button = uibutton(app.PlayPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button.Tag = 'LetterTiles';
            app.Button2 = uibutton(app.PlayPanel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button2.Tag = 'LetterTiles';
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.PlayPanel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button3.Tag = 'LetterTiles';
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.PlayPanel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button4.Tag = 'LetterTiles';
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.PlayPanel, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button5.Tag = 'LetterTiles';
            app.Button5.Text = 'Button5';
            app.EditField = uieditfield(app.PlayPanel, 'text');
            app.EditField.Tag = 'WordSlots';
            app.EditField2 = uieditfield(app.PlayPanel, 'text');
            app.EditField2.Tag = 'WordSlots';
            app.EditField3 = uieditfield(app.PlayPanel, 'text');
            app.EditField3.Tag = 'WordSlots';
            app.EditField4 = uieditfield(app.PlayPanel, 'text');
            app.EditField4.Tag = 'WordSlots';
            app.EditField5 = uieditfield(app.PlayPanel, 'text');
            app.EditField5.Tag = 'WordSlots';
            app.SubmitButton = uibutton(app.PlayPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'SubmitButton';
            app.WordCountdownLabel = uilabel(app.PlayPanel);
            app.WordCountdownLabel.Text = 'Word Countdown:';
            app.InstructionsPanel = uipanel(app.UIFigure);
            app.InstructionsPanel.Title = 'InstructionsPanel';
            app.InstructionsPanel.Visible = 'off';
            app.BacktoHomeButton = uibutton(app.InstructionsPanel, 'push');
            app.BacktoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoHomeButtonPushed, true);
            app.BacktoHomeButton.Text = 'Back to Home';
            app.TextArea = uitextarea(app.InstructionsPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Click and drag tiles from a series of scrambled letters. You will be given the count of possible valid words that can be created from rearranging the given letters. You must use all the tiles given. Once you enter a valid word, it will be added to a list on the side of your screen. Once you’ve entered all possible rearrangements of the letters, congratulations! You will be able to reset the game to generate another string of scrambled letters and play again! '};
            app.HowtoPlayLabel = uilabel(app.InstructionsPanel);
            app.HowtoPlayLabel.Text = 'How to Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end